classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FunctionGeneratorTab            matlab.ui.container.Tab
        SimulationtimeEditField         matlab.ui.control.NumericEditField
        SimulationtimeEditFieldLabel    matlab.ui.control.Label
        Phase6EditField                 matlab.ui.control.NumericEditField
        PhaseEditFieldLabel_5           matlab.ui.control.Label
        Phase5EditField                 matlab.ui.control.NumericEditField
        PhaseEditFieldLabel_4           matlab.ui.control.Label
        Phase4EditField                 matlab.ui.control.NumericEditField
        PhaseEditFieldLabel_3           matlab.ui.control.Label
        Phase3EditField                 matlab.ui.control.NumericEditField
        PhaseEditFieldLabel_2           matlab.ui.control.Label
        amplitude6EditField             matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel_5       matlab.ui.control.Label
        amplitude5EditField             matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel_4       matlab.ui.control.Label
        amplitude4EditField             matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel_3       matlab.ui.control.Label
        amplitude3EditField             matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel_2       matlab.ui.control.Label
        Phase2EditField                 matlab.ui.control.NumericEditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        amplitude2EditField             matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel         matlab.ui.control.Label
        thLabel_3                       matlab.ui.control.Label
        thLabel_2                       matlab.ui.control.Label
        thLabel                         matlab.ui.control.Label
        rdLabel                         matlab.ui.control.Label
        ndLabel                         matlab.ui.control.Label
        harmonicsLabel                  matlab.ui.control.Label
        fallingrisingtimeofDCEditField  matlab.ui.control.NumericEditField
        fallingrisingtimeofDCEditFieldLabel  matlab.ui.control.Label
        DccomponentEditField            matlab.ui.control.NumericEditField
        DccomponentEditFieldLabel       matlab.ui.control.Label
        SamplingfrequenctyEditField     matlab.ui.control.NumericEditField
        SamplingfrequenctyEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        PhaseshiftEditField             matlab.ui.control.NumericEditField
        PhaseshiftEditFieldLabel        matlab.ui.control.Label
        AcmaxamplitudeEditField         matlab.ui.control.NumericEditField
        AcmaxamplitudeEditFieldLabel    matlab.ui.control.Label
        DcTypeDropDown                  matlab.ui.control.DropDown
        DcTypeDropDownLabel             matlab.ui.control.Label
        GenerateattheendButton          matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            global k to flag
            a=app.AcmaxamplitudeEditField.Value;
            phase=app.PhaseshiftEditField.Value;
            freq=app.FrequencyEditField.Value;
            fsample=app.SamplingfrequenctyEditField.Value;
            Tsample=1/fsample;
            DCcomp=app.DccomponentEditField.Value;
            Tr=app.fallingrisingtimeofDCEditField.Value+10^(-1000);
            Tsim1=app.SimulationtimeEditField.Value;
            to=Tsim1;
            a2=app.amplitude2EditField.Value;
            a3=app.amplitude3EditField.Value;
            a4=app.amplitude4EditField.Value;
            a5=app.amplitude5EditField.Value;
            a6=app.amplitude6EditField.Value;
            phase2=app.Phase2EditField.Value;
            phase3=app.Phase3EditField.Value;
            phase4=app.Phase4EditField.Value;
            phase5=app.Phase5EditField.Value;
            phase6=app.Phase6EditField.Value;            
            AC1=a*sin((0:Tsample:Tsim1)*2*pi*freq+phase*pi/180);
            AC2=a2*sin((0:Tsample:Tsim1)*2*pi*freq*2+phase2*pi/180);
            AC3=a3*sin((0:Tsample:Tsim1)*2*pi*freq*3+phase3*pi/180);
            AC4=a4*sin((0:Tsample:Tsim1)*2*pi*freq*4+phase4*pi/180);
            AC5=a5*sin((0:Tsample:Tsim1)*2*pi*freq*5+phase5*pi/180);
            AC6=a6*sin((0:Tsample:Tsim1)*2*pi*freq*6+phase6*pi/180);
            if DCcomp~0;
                if Tr~0;
                    switch app.DcTypeDropDown.Value
                        case 'Constant'
                            k=AC1+AC2+AC3+AC4+AC5+AC6+DCcomp;
                        case 'Decaying'
                    
                            k=AC1+AC2+AC3+AC4+AC5+AC6;
                            for i=1:length(k)
                                DCcomp1=DCcomp-DCcomp*Tsample/Tr*(i-1);
                                if DCcomp1<=0;
                                    DCcomp1=0;
                                end
                                k(i)=k(i)+DCcomp1;
                            end
                        case 'Rising'
                            k=AC1+AC2+AC3+AC4+AC5+AC6;
                            for i=1:length(k)
                                DCcomp1=DCcomp*Tsample/Tr*(i-1);
                                if DCcomp1>=DCcomp;
                                    DCcomp1=DCcomp;
                                end
                                k(i)=k(i)+DCcomp1;
                            end
                    end
                else
                    k=AC1+AC2+AC3+AC4+AC5+AC6+DCcomp;
                end
            else
                k=AC1+AC2+AC3+AC4+AC5+AC6;
            end
            plot(app.UIAxes,0:Tsample:Tsim1,k)
            flag=1;
        end

        % Button pushed function: GenerateattheendButton
        function GenerateattheendButtonPushed(app, event)
            global to k flag
            if flag==1
            a=app.AcmaxamplitudeEditField.Value;
            phase=app.PhaseshiftEditField.Value;
            freq=app.FrequencyEditField.Value;
            fsample=app.SamplingfrequenctyEditField.Value;
            Tsample=1/fsample;
            DCcomp=app.DccomponentEditField.Value;
            Tr=app.fallingrisingtimeofDCEditField.Value;
            Tsim1=app.SimulationtimeEditField.Value;
            a2=app.amplitude2EditField.Value;
            a3=app.amplitude3EditField.Value;
            a4=app.amplitude4EditField.Value;
            a5=app.amplitude5EditField.Value;
            a6=app.amplitude6EditField.Value;
            phase2=app.Phase2EditField.Value;
            phase3=app.Phase3EditField.Value;
            phase4=app.Phase4EditField.Value;
            phase5=app.Phase5EditField.Value;
            phase6=app.Phase6EditField.Value;            
            AC1=a*sin((0:Tsample:Tsim1)*2*pi*freq+phase*pi/180);
            AC2=a2*sin((0:Tsample:Tsim1)*2*pi*freq*2+phase2*pi/180);
            AC3=a3*sin((0:Tsample:Tsim1)*2*pi*freq*3+phase3*pi/180);
            AC4=a4*sin((0:Tsample:Tsim1)*2*pi*freq*4+phase4*pi/180);
            AC5=a5*sin((0:Tsample:Tsim1)*2*pi*freq*5+phase5*pi/180);
            AC6=a6*sin((0:Tsample:Tsim1)*2*pi*freq*6+phase6*pi/180);
            if DCcomp~0;
                if Tr~0;
                    switch app.DcTypeDropDown.Value
                        case 'Constant'
                            k2=AC1+AC2+AC3+AC4+AC5+AC6+DCcomp;
                        case 'Decaying'
                    
                            k2=AC1+AC2+AC3+AC4+AC5+AC6;
                            for i=1:length(k2)
                                DCcomp1=DCcomp-DCcomp*Tsample/Tr*(i-1);
                                if DCcomp1<=0;
                                    DCcomp1=0;
                                end
                                k2(i)=k2(i)+DCcomp1;
                            end
                        case 'Rising'
                            k2=AC1+AC2+AC3+AC4+AC5+AC6;
                            for i=1:length(k2)
                                DCcomp1=DCcomp*Tsample/Tr*(i-1);
                                if DCcomp1>=DCcomp;
                                    DCcomp1=DCcomp;
                                end
                                k2(i)=k2(i)+DCcomp1;
                            end
                    end
                else
                    k2=AC1+AC2+AC3+AC4+AC5+AC6+DCcomp;
                end
            else
                k2=AC1+AC2+AC3+AC4+AC5+AC6;
            end
            to=to+Tsim1;
            k2(:,1)=[];
            k=[k,k2];
            plot(app.UIAxes,0:Tsample:to,k)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FunctionGeneratorTab = uitab(app.TabGroup);
            app.FunctionGeneratorTab.Title = 'Function Generator';
            app.UIAxes = uiaxes(app.FunctionGeneratorTab);
            title(app.UIAxes, 'Wave')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.GenerateButton = uibutton(app.FunctionGeneratorTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.GenerateattheendButton = uibutton(app.FunctionGeneratorTab, 'push');
            app.GenerateattheendButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateattheendButtonPushed, true);
            app.GenerateattheendButton.Text = 'Generate at the end';
            app.DcTypeDropDownLabel = uilabel(app.FunctionGeneratorTab);
            app.DcTypeDropDownLabel.Text = 'Dc Type';
            app.DcTypeDropDown = uidropdown(app.FunctionGeneratorTab);
            app.DcTypeDropDown.Items = {'Rising', 'Decaying', 'Constant'};
            app.DcTypeDropDown.Value = 'Constant';
            app.AcmaxamplitudeEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.AcmaxamplitudeEditFieldLabel.Text = 'Ac max amplitude';
            app.AcmaxamplitudeEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.PhaseshiftEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.PhaseshiftEditFieldLabel.Text = 'Phase shift';
            app.PhaseshiftEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.SamplingfrequenctyEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.SamplingfrequenctyEditFieldLabel.Text = 'Sampling frequencty';
            app.SamplingfrequenctyEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.DccomponentEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.DccomponentEditFieldLabel.Text = 'Dc component';
            app.DccomponentEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.fallingrisingtimeofDCEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.fallingrisingtimeofDCEditFieldLabel.Text = 'falling/rising time of DC';
            app.fallingrisingtimeofDCEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.harmonicsLabel = uilabel(app.FunctionGeneratorTab);
            app.harmonicsLabel.Text = 'harmonics';
            app.ndLabel = uilabel(app.FunctionGeneratorTab);
            app.ndLabel.Text = '2nd';
            app.rdLabel = uilabel(app.FunctionGeneratorTab);
            app.rdLabel.Text = '3rd';
            app.thLabel = uilabel(app.FunctionGeneratorTab);
            app.thLabel.Text = '4th';
            app.thLabel_2 = uilabel(app.FunctionGeneratorTab);
            app.thLabel_2.Text = '5th';
            app.thLabel_3 = uilabel(app.FunctionGeneratorTab);
            app.thLabel_3.Text = '6th';
            app.amplitudeEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitude2EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.Phase2EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitudeEditFieldLabel_2 = uilabel(app.FunctionGeneratorTab);
            app.amplitudeEditFieldLabel_2.Text = 'amplitude';
            app.amplitude3EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitudeEditFieldLabel_3 = uilabel(app.FunctionGeneratorTab);
            app.amplitudeEditFieldLabel_3.Text = 'amplitude';
            app.amplitude4EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitudeEditFieldLabel_4 = uilabel(app.FunctionGeneratorTab);
            app.amplitudeEditFieldLabel_4.Text = 'amplitude';
            app.amplitude5EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.amplitudeEditFieldLabel_5 = uilabel(app.FunctionGeneratorTab);
            app.amplitudeEditFieldLabel_5.Text = 'amplitude';
            app.amplitude6EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.PhaseEditFieldLabel_2 = uilabel(app.FunctionGeneratorTab);
            app.PhaseEditFieldLabel_2.Text = 'Phase';
            app.Phase3EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.PhaseEditFieldLabel_3 = uilabel(app.FunctionGeneratorTab);
            app.PhaseEditFieldLabel_3.Text = 'Phase';
            app.Phase4EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.PhaseEditFieldLabel_4 = uilabel(app.FunctionGeneratorTab);
            app.PhaseEditFieldLabel_4.Text = 'Phase';
            app.Phase5EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.PhaseEditFieldLabel_5 = uilabel(app.FunctionGeneratorTab);
            app.PhaseEditFieldLabel_5.Text = 'Phase';
            app.Phase6EditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.SimulationtimeEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.SimulationtimeEditFieldLabel.Text = 'Simulation time';
            app.SimulationtimeEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end